classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BinsThresholdingEditFieldLabel_3  matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        RetinalBloodVesselSegmentationforRetCam3ImagesToolLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EditField_3                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        ChooseFolderButton              matlab.ui.control.Button
        PerformSegmentationButton       matlab.ui.control.Button
        BinsThresholdingEditFieldLabel_2  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        SelectFilterDropDown            matlab.ui.control.DropDown
        SelectFilterDropDownLabel       matlab.ui.control.Label
        BinsThresholdingEditField       matlab.ui.control.EditField
        BinsThresholdingEditFieldLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        SelectImageButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        file = '';
        path = '';
        cielabEnh = 0; 
        odFilter = 0;
        ttBins = '';
        filterUsed = 'combined';
        distFolder = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function selectImage(app, event)
            [app.file.Value,app.path.Value] = uigetfile('*.jpg');
            app.EditField.Value = fullfile(app.file.Value);
            
            app.Image.Visible = "on";

            img_folder = convertCharsToStrings(app.path.Value);
            img_name = convertCharsToStrings(app.file.Value);
            img_path = img_folder + img_name;
            image = im2double(imread(img_path));
            app.Image.ImageSource = image;
        end

        % Button pushed function: PerformSegmentationButton
        function runSegmentation(app, event)
            % Path for the external Filter functions used
            app.EditField_3.Value = "";
            if strcmp(app.ttBins,'') 
                app.EditField_3.Value = "Please choose the number of bins for Thresholding";
                return;
            elseif strcmp(app.file, '')
                app.EditField_3.Value = "Please choose an image to segment";
                return;
            elseif strcmp(app.distFolder, '')
                app.EditField_3.Value = "Please choose a distination folder";
                return;
            end
           

            addpath('frangi_filter_version2a\');
            addpath('jerman_filter\')
            
            img_folder = convertCharsToStrings(app.path.Value);
            img_name = convertCharsToStrings(app.file.Value);
            img_path = img_folder + img_name;
            image = im2double(imread(img_path));
            
            segImg = segmentRetinalImage(image,app.filterUsed,app.ttBins,app.cielabEnh);
            
            
            if app.odFilter
                dist_th = 30;
                axisRatio_th = 4.5;
                area_th = 1500;
                RetCam = 1;
                windowSize = 50;
                ODMask = FilterOpticalDisk(image, segImg, RetCam, dist_th, axisRatio_th, area_th, windowSize);
            else
                ODMask = segImg;
            end
            
            seg_name =  app.distFolder.Value +  "\Seg_" + img_name;
            imwrite(ODMask, seg_name);

            image_name = split(img_name, '.');
            matSeg_name = app.distFolder.Value + "\Seg_" + image_name(1) + ".mat";
            save(matSeg_name, "ODMask");

            skel = bwskel(ODMask);

            skel_name =  app.distFolder.Value +  "\Skel_" + img_name;
            imwrite(skel, skel_name);

            matSkel_name = app.distFolder.Value + "\Skel_" + image_name(1) + ".mat";
            save(matSkel_name, "skel");
            
            R = image(:,:,1); R(ODMask) = 0;
            G = image(:,:,2); 
            B = image(:,:,3);
            fusedImg = cat(3, R,G,B);

            ol_name =  app.distFolder.Value +  "\OL_" + img_name;
            imwrite(fusedImg, ol_name);
            
            app.Image.ImageSource = fusedImg;
            app.EditField_3.Value = "Segmentation Concluded";
        end

        % Value changed function: CheckBox
        function cielabValue(app, event)
            value = app.CheckBox.Value;
            app.cielabEnh = value;
        end

        % Value changed function: BinsThresholdingEditField
        function binsValue(app, event)
            value = app.BinsThresholdingEditField.Value;
            app.ttBins = value;
        end

        % Value changed function: SelectFilterDropDown
        function selectFilter(app, event)
            value = app.SelectFilterDropDown.Value;
            
            if strcmp(value,"Combined Enhancement")
                app.filterUsed = 'combined';
            elseif strcmp(value,"Frangi Filter")
                app.filterUsed = 'frangi';
            elseif strcmp(value,"Jerman Filer")
                app.filterUsed = 'jerman';
            end

            
        end

        % Button pushed function: ChooseFolderButton
        function selectFolder(app, event)
            app.distFolder.Value = uigetdir('*');
            app.EditField_2.Value = app.distFolder.Value;
        end

        % Value changed function: CheckBox_2
        function odArtifact(app, event)
            value = app.CheckBox_2.Value;
            app.odFilter = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @selectImage, true);
            app.SelectImageButton.Text = 'Select Image';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.BinsThresholdingEditFieldLabel = uilabel(app.UIFigure);
            app.BinsThresholdingEditFieldLabel.Text = 'Bins Thresholding';
            app.BinsThresholdingEditField = uieditfield(app.UIFigure, 'text');
            app.BinsThresholdingEditField.ValueChangedFcn = createCallbackFcn(app, @binsValue, true);
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'Combined Enhancement', 'Frangi Filter', 'Jerman Filter'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @selectFilter, true);
            app.SelectFilterDropDown.Value = 'Combined Enhancement';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @cielabValue, true);
            app.CheckBox.Tag = 'cielabEnh';
            app.BinsThresholdingEditFieldLabel_2 = uilabel(app.UIFigure);
            app.BinsThresholdingEditFieldLabel_2.Text = 'CIELAB Enhancement';
            app.PerformSegmentationButton = uibutton(app.UIFigure, 'push');
            app.PerformSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @runSegmentation, true);
            app.PerformSegmentationButton.Text = 'Perform Segmentation';
            app.ChooseFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectFolder, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.RetinalBloodVesselSegmentationforRetCam3ImagesToolLabel = uilabel(app.UIFigure);
            app.RetinalBloodVesselSegmentationforRetCam3ImagesToolLabel.Text = 'Retinal Blood Vessel Segmentation for RetCam3 Images Tool';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @odArtifact, true);
            app.CheckBox_2.Tag = 'cielabEnh';
            app.BinsThresholdingEditFieldLabel_3 = uilabel(app.UIFigure);
            app.BinsThresholdingEditFieldLabel_3.Text = 'OD Artifact Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end